classdef simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ParticleoverlayButtonGroup      matlab.ui.container.ButtonGroup
        noButton                        matlab.ui.control.RadioButton
        yesButton                       matlab.ui.control.RadioButton
        BL_SP_D                         matlab.ui.control.Label
        BL_SP_unit                      matlab.ui.control.Label
        BL_SP_SZ                        matlab.ui.control.NumericEditField
        nm2s1Label                      matlab.ui.control.Label
        pxLabel                         matlab.ui.control.Label
        Blur_Model_select               matlab.ui.container.ButtonGroup
        SP_PTC                          matlab.ui.control.RadioButton
        samePTC                         matlab.ui.control.RadioButton
        F_R_RD                          matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        Diffusivity                     matlab.ui.control.NumericEditField
        MotionblurDLabel                matlab.ui.control.Label
        F_R_FX                          matlab.ui.control.NumericEditField
        FilterradiusLabel               matlab.ui.control.Label
        randomD                         matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        randLabel_2                     matlab.ui.control.Label
        randomIMFP                      matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        randLabel                       matlab.ui.control.Label
        fixedLabel                      matlab.ui.control.Label
        Particle_GT_RD                  matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        Particle_NON_FX                 matlab.ui.control.NumericEditField
        BlurryparticleEditFieldLabel    matlab.ui.control.Label
        Particle_GT_FX                  matlab.ui.control.NumericEditField
        ParticleEditFieldLabel          matlab.ui.control.Label
        D_SP_GT                         matlab.ui.control.NumericEditField
        DiameternmEditFieldLabel        matlab.ui.control.Label
        Dispersity                      matlab.ui.control.NumericEditField
        DispersityEditFieldLabel        matlab.ui.control.Label
        T_PSM_GT                        matlab.ui.control.NumericEditField
        ThicknessnmEditFieldLabel       matlab.ui.control.Label
        LEN_PSM_GT                      matlab.ui.control.NumericEditField
        EdgelengthnmEditFieldLabel      matlab.ui.control.Label
        FailedLabel                     matlab.ui.control.Label
        SucessLabel                     matlab.ui.control.Label
        IMFPwaterEditField              matlab.ui.control.NumericEditField
        IMFPwaterEditFieldLabel         matlab.ui.control.Label
        IMFPSiNEditField                matlab.ui.control.NumericEditField
        IMFPSiNEditFieldLabel           matlab.ui.control.Label
        IMFPparticleEditField           matlab.ui.control.NumericEditField
        IMFPparticleEditFieldLabel      matlab.ui.control.Label
        GaussiannoiseEditField          matlab.ui.control.NumericEditField
        GaussiannoiseEditFieldLabel     matlab.ui.control.Label
        LiquidthicknessEditField        matlab.ui.control.NumericEditField
        LiquidthicknessEditFieldLabel   matlab.ui.control.Label
        SiNwindowthicknessEditField     matlab.ui.control.NumericEditField
        SiNwindowthicknessEditFieldLabel  matlab.ui.control.Label
        ConversionFactorEditField       matlab.ui.control.NumericEditField
        ConversionFactorEditFieldLabel  matlab.ui.control.Label
        ExposuretimeEditField           matlab.ui.control.NumericEditField
        ExposuretimeEditFieldLabel      matlab.ui.control.Label
        DoserateEditField               matlab.ui.control.NumericEditField
        DoserateEditFieldLabel          matlab.ui.control.Label
        realsizeEditField               matlab.ui.control.NumericEditField
        realsizeEditFieldLabel          matlab.ui.control.Label
        ImagesizeEditField              matlab.ui.control.NumericEditField
        ImagesizeEditFieldLabel         matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        ParticleLabel_2                 matlab.ui.control.Label
        NoiseLabel                      matlab.ui.control.Label
        LoadMTFButton                   matlab.ui.control.Button
        ShotnoiseButtonGroup            matlab.ui.container.ButtonGroup
        onButton                        matlab.ui.control.RadioButton
        offButton_2                     matlab.ui.control.RadioButton
        UseMTFButtonGroup               matlab.ui.container.ButtonGroup
        onButton_2                      matlab.ui.control.RadioButton
        offButton_3                     matlab.ui.control.RadioButton
        nmLabel_5                       matlab.ui.control.Label
        nmx2Label                       matlab.ui.control.Label
        counteLabel                     matlab.ui.control.Label
        eA2s1Label                      matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        nmLabel_4                       matlab.ui.control.Label
        pixelLabel                      matlab.ui.control.Label
        nmLabel_3                       matlab.ui.control.Label
        nmLabel_2                       matlab.ui.control.Label
        nmLabel                         matlab.ui.control.Label
        ConstantsLabel                  matlab.ui.control.Label
        ImagingparametersLabel          matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        SNRValue                        matlab.ui.control.Label
        SNRLabel                        matlab.ui.control.Label
        sampleN                         matlab.ui.control.Label
        doneN                           matlab.ui.control.Label
        ImagepathEditField              matlab.ui.control.EditField
        ImagepathEditFieldLabel         matlab.ui.control.Label
        GroundtruthpathEditField        matlab.ui.control.EditField
        GroundtruthpathLabel            matlab.ui.control.Label
        SamplenumberEditField           matlab.ui.control.NumericEditField
        SamplenumberEditFieldLabel      matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        Label_4                         matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        StdLabel_2                      matlab.ui.control.Label
        StdLabel                        matlab.ui.control.Label
        MeanLabel_2                     matlab.ui.control.Label
        MeanLabel                       matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        ParticleLabel                   matlab.ui.control.Label
        BackgroundLabel                 matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        PreviewButton                   matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        MTFmatrix;
        PARTICLERES = 300;
        sphere_model;
    end
    
    methods (Access = public)
        function result = stack_channel(~,I0)
            I = cat(3,I0,I0);
            result = cat(3,I,I0);
        end
        
        function [gt,sim] = image_sim(app)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%
            detectorRES = app.ImagesizeEditField.Value;
            detectorScale = app.realsizeEditField.Value/detectorRES; %nm/px
            exposureTime = app.ExposuretimeEditField.Value;
            IMFPwater = app.IMFPwaterEditField.Value;
            IMFPSiNx = app.IMFPSiNEditField.Value;
            thicknessWater = app.LiquidthicknessEditField.Value;
            thicknessCell = app.SiNwindowthicknessEditField.Value;
            conversionFactor = app.ConversionFactorEditField.Value;
            doseRate = app.DoserateEditField.Value + floor(rand()*app.randomD.Value);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            incident_e_count=doseRate*100*(detectorScale^2)*exposureTime;%electrons/(pixel)    
            water=ones(detectorRES)*thicknessWater;
            cell=ones(detectorRES)*2*thicknessCell;
            canvas=zeros(detectorRES+2*app.PARTICLERES,detectorRES+2*app.PARTICLERES);
            canvas_lambda=zeros(detectorRES+2*app.PARTICLERES,detectorRES+2*app.PARTICLERES);
            gt=zeros(detectorRES+2*app.PARTICLERES,detectorRES+2*app.PARTICLERES);
            particleN=app.Particle_GT_FX.Value + floor(rand()*app.Particle_GT_RD.Value);
            particleN=particleN*((app.PARTICLERES+detectorRES)^2)/(detectorRES^2);
            fastparticleN=app.Particle_NON_FX.Value;
            fastparticleN=fastparticleN*((app.PARTICLERES+detectorRES)^2)/(detectorRES^2);
            for i =1 :particleN
                
                IMFPgold = app.IMFPparticleEditField.Value + floor(rand()*app.randomIMFP.Value);
                pos=randi([1,detectorRES+app.PARTICLERES],[1 2])';
                particle=particle_function(app);
                if (app.noButton.Value)
                    for NTry = 1 : 20
                        gt_sg = gt*0;
                        gt_sg(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)=gt_sg(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)+particle;
                        indicator = and(logical(gt_sg),logical(gt));
                        if(sum(indicator(:))>0)
                            pos=randi([1,detectorRES+app.PARTICLERES],[1 2])';
                        else
                            gt(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)=gt(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)+particle;
                            particle=bokeh(particle,[app.F_R_FX.Value,app.F_R_RD.Value]);
                            canvas(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)=canvas(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)+particle;
                            canvas_lambda(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)=canvas_lambda(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)+particle/IMFPgold;
                            break;
                        end
                    end
                    
                else
                    gt(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)=gt(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)+particle;
                    particle=bokeh(particle,[app.F_R_FX.Value,app.F_R_RD.Value]);
                    canvas(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)=canvas(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)+particle;
                    canvas_lambda(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)=canvas_lambda(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)+particle/IMFPgold;
                end
            
            end
    
            for i =1 :fastparticleN
                IMFPgold = app.IMFPparticleEditField.Value + floor(rand()*app.randomIMFP.Value);
                pos=randi([1,detectorRES+app.PARTICLERES],[1 2])';
                if (app.samePTC.Value == 1)
                   particle=particle_function(app);
                else 
                   particle = app.sphere_model; 
                end                
                particle=motionBlur_RW(particle,app.Diffusivity.Value/(detectorScale^2),exposureTime);
                canvas(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)=canvas(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)+particle;
                canvas_lambda(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)=canvas_lambda(pos(1):pos(1)+app.PARTICLERES-1,pos(2):pos(2)+app.PARTICLERES-1)+particle/IMFPgold;
            end
    
            gt=~logical(gt(app.PARTICLERES+1:app.PARTICLERES+detectorRES,app.PARTICLERES+1:app.PARTICLERES+detectorRES));
            canvas=canvas(app.PARTICLERES+1:app.PARTICLERES+detectorRES,app.PARTICLERES+1:app.PARTICLERES+detectorRES);
            canvas_lambda=canvas_lambda(app.PARTICLERES+1:app.PARTICLERES+detectorRES,app.PARTICLERES+1:app.PARTICLERES+detectorRES);
            water=water-canvas;
            sim0=incident_e_count*exp(-(canvas_lambda+water/IMFPwater+cell/IMFPSiNx));% #electrons in this pixel in this frame 
            
            if app.offButton_2.Value
                sim=sim0;
            else
                sim=poissrnd(sim0);
            end
            
            gt=gt(:,:)>0.5;
            sim= normrnd(sim,app.GaussiannoiseEditField.Value);
            
            if ~app.offButton_3.Value
                FT=fft2(sim);
                FT=fftshift(FT);
                FT=FT.*app.MTFmatrix;
                FT=ifftshift(FT);
                sim=abs(ifft2(FT));
            end
            
            sim=sim*conversionFactor;
            end
    
        function im = particle_function(app)
            value = app.ModelDropDown.Value;
            detectorScale = app.realsizeEditField.Value/app.ImagesizeEditField.Value;
            switch value
            case 'Prism'
                im = prism(app.LEN_PSM_GT.Value,app.Dispersity.Value,app.T_PSM_GT.Value,app.PARTICLERES,detectorScale);
            case 'Concave cube'
                im = concave_cube(app.LEN_PSM_GT.Value,app.Dispersity.Value,app.PARTICLERES,detectorScale);
            case 'Sphere'
                im = sphere_rasterization(app.D_SP_GT.Value,app.Dispersity.Value,app.PARTICLERES,detectorScale);
            case 'Rod'
                im = rod_rasterization(app.PARTICLERES,detectorScale);
            case '<select>'
                im = 0;
            end
                
                
        end
            
        function prism_vis(app,x)
            app.LEN_PSM_GT.Visible = x;
            app.EdgelengthnmEditFieldLabel.Visible = x;
            
            app.T_PSM_GT.Visible = x;
            app.ThicknessnmEditFieldLabel.Visible = x;
            
            app.Dispersity.Visible = x;
            app.DispersityEditFieldLabel.Visible = x;
        end
        
        function sphere_vis(app,x)
            app.D_SP_GT.Visible = x;
            app.DiameternmEditFieldLabel.Visible = x;

            app.Dispersity.Visible = x;
            app.DispersityEditFieldLabel.Visible = x;
        end
        
        function concave_cube_vis(app,x)
            app.LEN_PSM_GT.Visible = x;
            app.EdgelengthnmEditFieldLabel.Visible = x;

            app.Dispersity.Visible = x;
            app.DispersityEditFieldLabel.Visible = x;
        end
        
        function rod_vis(app,x)
            %app.EdgelengthEditField_2.Visible = x;
            %app.EdgelengthEditField_2Label.Visible = x;
            sadFrog = 1;
            %app.DispersityEditField_3.Visible = x;
            %app.DispersityEditField_3Label.Visible = x;
        end
        
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('functions\');

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {660, 660};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {506, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            [gt,sim] = image_sim(app);
            BKG_mean = mean(sim(gt),'all');
            BKG_std = std(sim(gt),0,'all');
            app.Label_11.Text = num2str(BKG_mean);            
            app.Label_12.Text = num2str(BKG_std);   
            app.SNRValue.Text = num2str(BKG_mean/BKG_std);   
            
            PTC_mean = mean(sim(~gt),'all');
            PTC_std = std(sim(~gt),0,'all');
            app.Label_2.Text = num2str(PTC_mean);  
            app.Label_4.Text = num2str(PTC_std);   
            
            sim=sim-min(sim(:));
            sim=sim/max(sim(:));
            sim = stack_channel(app,sim);
            gt = stack_channel(app,gt);
            app.Image.ImageSource = sim;
            app.Image2.ImageSource = double(gt);
        end

        % Button pushed function: LoadMTFButton
        function LoadMTFButtonPushed(app, event)
            detectorRES = app.ImagesizeEditField.Value;
            
            try 
                app.MTFmatrix=load(strcat('MTF data/MTFmatrix',num2str(detectorRES),'.mat'));app.MTFmatrix=app.MTFmatrix.MTFmatrix;
                app.PreviewButton.Enable = 1;
                app.GenerateButton.Enable = 1;
                app.SucessLabel.Visible = 1;
                app.FailedLabel.Visible = 0;
            catch exception
                app.SucessLabel.Visible = 0;
                app.FailedLabel.Visible = 1;
            end
        end

        % Selection changed function: UseMTFButtonGroup
        function UseMTFButtonGroupSelectionChanged(app, event)
            if (app.offButton_3.Value == 0)
                app.PreviewButton.Enable = 0;
                app.GenerateButton.Enable = 0;
                app.LoadMTFButton.Enable = 1;
            end
            if (app.offButton_3.Value == 1)
                app.PreviewButton.Enable = 1;
                app.GenerateButton.Enable = 1;
                app.LoadMTFButton.Enable = 0;
            end
            
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            switch value
                case '<select>' 
                    sphere_vis(app,0)
                    concave_cube_vis(app,0)
                    rod_vis(app,0)
                    prism_vis(app,0);
                case 'Prism'
                    sphere_vis(app,0)
                    concave_cube_vis(app,0)
                    rod_vis(app,0)
                    prism_vis(app,1);
                case 'Concave cube'
                    prism_vis(app,0);
                    sphere_vis(app,0)
                    rod_vis(app,0)
                    concave_cube_vis(app,1)
                case 'Sphere'
                    prism_vis(app,0);
                    concave_cube_vis(app,0)
                    rod_vis(app,0)
                    sphere_vis(app,1)
                case 'Rod'
                    prism_vis(app,0);
                    sphere_vis(app,0)
                    concave_cube_vis(app,0)
                    rod_vis(app,1)
            end
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            if ~exist(app.GroundtruthpathEditField.Value, 'dir')
                mkdir(app.GroundtruthpathEditField.Value)
            end
            if ~exist(app.ImagepathEditField.Value, 'dir')
                mkdir(app.ImagepathEditField.Value)
            end    
        
        for i = 1 : app.SamplenumberEditField.Value
            [gt,sim] = image_sim(app);
            BKG_mean = mean(sim(gt),'all');
            BKG_std = std(sim(gt),0,'all');
            app.Label_11.Text = num2str(BKG_mean);            
            app.Label_12.Text = num2str(BKG_std);   
            
            PTC_mean = mean(sim(~gt),'all');
            PTC_std = std(sim(~gt),0,'all');
            app.Label_2.Text = num2str(PTC_mean);  
            app.Label_4.Text = num2str(PTC_std);   
            
            sim=sim-min(sim(:));
            sim=sim/max(sim(:));
            
            
            %%%%%%%%save%%%%%%%
            gtname = [app.GroundtruthpathEditField.Value,'/',num2str(i),'.tif'];
            simname = [app.ImagepathEditField.Value,'/',num2str(i),'.tif'];
            sim=imresize(sim,[512,512]);
            gt=imresize(gt,[512,512]);
            imwrite(sim,simname);
            imwrite(gt,gtname);
            %%%%%%%%display%%%%%%%%%%
            sim = stack_channel(app,sim);
            gt = stack_channel(app,gt);
            app.Image.ImageSource = sim;
            app.Image2.ImageSource = double(gt);
            %%%%%%%%update number%%%%%%%%%%
            app.doneN.Text = num2str(i) ;
            app.sampleN.Text  = ['/' ,num2str(app.SamplenumberEditField.Value)] ;
            pause (0.01) 
            
        end
        end

        % Selection changed function: Blur_Model_select
        function Blur_Model_selectSelectionChanged(app, event)

            if(app.samePTC.Value == 1)
                app.BL_SP_SZ.Visible = 0;
                app.BL_SP_unit.Visible = 0;
                app.BL_SP_D.Visible = 0;
            else
                app.BL_SP_SZ.Visible = 1;
                app.BL_SP_unit.Visible = 1;
                app.BL_SP_D.Visible = 1;
                app.sphere_model = sphere_rasterization(app.BL_SP_SZ.Value,0,app.PARTICLERES,app.realsizeEditField.Value/app.ImagesizeEditField.Value);
            end
            
        end

        % Value changed function: BL_SP_SZ
        function BL_SP_SZValueChanged(app, event)
            app.sphere_model = sphere_rasterization(app.BL_SP_SZ.Value,0,app.PARTICLERES,app.realsizeEditField.Value/app.ImagesizeEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImagingparametersLabel = uilabel(app.LeftPanel);
            app.ImagingparametersLabel.Text = 'Imaging parameters:';
            app.ConstantsLabel = uilabel(app.LeftPanel);
            app.ConstantsLabel.Text = 'Constants:';
            app.nmLabel = uilabel(app.LeftPanel);
            app.nmLabel.Text = 'nm';
            app.nmLabel_2 = uilabel(app.LeftPanel);
            app.nmLabel_2.Text = 'nm';
            app.nmLabel_3 = uilabel(app.LeftPanel);
            app.nmLabel_3.Text = 'nm';
            app.pixelLabel = uilabel(app.LeftPanel);
            app.pixelLabel.Text = 'pixel';
            app.nmLabel_4 = uilabel(app.LeftPanel);
            app.nmLabel_4.Text = 'nm';
            app.sLabel = uilabel(app.LeftPanel);
            app.sLabel.Text = 's';
            app.eA2s1Label = uilabel(app.LeftPanel);
            app.eA2s1Label.Text = 'e-A-2s-1';
            app.counteLabel = uilabel(app.LeftPanel);
            app.counteLabel.Text = 'count/e-';
            app.nmx2Label = uilabel(app.LeftPanel);
            app.nmx2Label.Text = 'nm (x2)';
            app.nmLabel_5 = uilabel(app.LeftPanel);
            app.nmLabel_5.Text = 'nm';
            app.UseMTFButtonGroup = uibuttongroup(app.LeftPanel);
            app.UseMTFButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UseMTFButtonGroupSelectionChanged, true);
            app.UseMTFButtonGroup.Title = 'Use MTF';
            app.offButton_3 = uiradiobutton(app.UseMTFButtonGroup);
            app.offButton_3.Text = 'off';
            app.offButton_3.Value = true;
            app.onButton_2 = uiradiobutton(app.UseMTFButtonGroup);
            app.onButton_2.Text = 'on';
            app.ShotnoiseButtonGroup = uibuttongroup(app.LeftPanel);
            app.ShotnoiseButtonGroup.Title = 'Shot noise';
            app.offButton_2 = uiradiobutton(app.ShotnoiseButtonGroup);
            app.offButton_2.Text = 'off';
            app.offButton_2.Value = true;
            app.onButton = uiradiobutton(app.ShotnoiseButtonGroup);
            app.onButton.Text = 'on';
            app.LoadMTFButton = uibutton(app.LeftPanel, 'push');
            app.LoadMTFButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMTFButtonPushed, true);
            app.LoadMTFButton.Enable = 'off';
            app.LoadMTFButton.Text = 'Load MTF';
            app.NoiseLabel = uilabel(app.LeftPanel);
            app.NoiseLabel.Text = 'Noise:';
            app.ParticleLabel_2 = uilabel(app.LeftPanel);
            app.ParticleLabel_2.Text = 'Particle:';
            app.ModelDropDownLabel = uilabel(app.LeftPanel);
            app.ModelDropDownLabel.Text = 'Model:';
            app.ModelDropDown = uidropdown(app.LeftPanel);
            app.ModelDropDown.Items = {'<select>', 'Prism', 'Concave cube', 'Sphere', 'Rod', ''};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = '<select>';
            app.ImagesizeEditFieldLabel = uilabel(app.LeftPanel);
            app.ImagesizeEditFieldLabel.Text = 'Image size';
            app.ImagesizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ImagesizeEditField.Limits = [28 2048];
            app.ImagesizeEditField.Value = 512;
            app.realsizeEditFieldLabel = uilabel(app.LeftPanel);
            app.realsizeEditFieldLabel.Text = 'real size';
            app.realsizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.realsizeEditField.Limits = [1 10000];
            app.realsizeEditField.Value = 1070;
            app.DoserateEditFieldLabel = uilabel(app.LeftPanel);
            app.DoserateEditFieldLabel.Text = 'Dose rate';
            app.DoserateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DoserateEditField.Value = 100;
            app.ExposuretimeEditFieldLabel = uilabel(app.LeftPanel);
            app.ExposuretimeEditFieldLabel.Text = 'Exposure time';
            app.ExposuretimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ExposuretimeEditField.Value = 0.1;
            app.ConversionFactorEditFieldLabel = uilabel(app.LeftPanel);
            app.ConversionFactorEditFieldLabel.Text = 'Conversion Factor';
            app.ConversionFactorEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ConversionFactorEditField.Value = 3.47;
            app.SiNwindowthicknessEditFieldLabel = uilabel(app.LeftPanel);
            app.SiNwindowthicknessEditFieldLabel.Text = 'SiN window thickness';
            app.SiNwindowthicknessEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SiNwindowthicknessEditField.Value = 50;
            app.LiquidthicknessEditFieldLabel = uilabel(app.LeftPanel);
            app.LiquidthicknessEditFieldLabel.Text = 'Liquid thickness';
            app.LiquidthicknessEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LiquidthicknessEditField.Value = 100;
            app.GaussiannoiseEditFieldLabel = uilabel(app.LeftPanel);
            app.GaussiannoiseEditFieldLabel.Text = 'Gaussian noise';
            app.GaussiannoiseEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IMFPparticleEditFieldLabel = uilabel(app.LeftPanel);
            app.IMFPparticleEditFieldLabel.Text = 'IMFP particle';
            app.IMFPparticleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IMFPparticleEditField.Limits = [0 500];
            app.IMFPparticleEditField.Value = 85;
            app.IMFPSiNEditFieldLabel = uilabel(app.LeftPanel);
            app.IMFPSiNEditFieldLabel.Text = 'IMFP SiN';
            app.IMFPSiNEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IMFPSiNEditField.Limits = [0 500];
            app.IMFPSiNEditField.Value = 144;
            app.IMFPwaterEditFieldLabel = uilabel(app.LeftPanel);
            app.IMFPwaterEditFieldLabel.Text = 'IMFP water';
            app.IMFPwaterEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IMFPwaterEditField.Limits = [0 500];
            app.IMFPwaterEditField.Value = 185;
            app.SucessLabel = uilabel(app.LeftPanel);
            app.SucessLabel.Visible = 'off';
            app.SucessLabel.Text = 'Sucess';
            app.FailedLabel = uilabel(app.LeftPanel);
            app.FailedLabel.Visible = 'off';
            app.FailedLabel.Text = 'Failed';
            app.EdgelengthnmEditFieldLabel = uilabel(app.LeftPanel);
            app.EdgelengthnmEditFieldLabel.Visible = 'off';
            app.EdgelengthnmEditFieldLabel.Text = 'Edge length (nm)';
            app.LEN_PSM_GT = uieditfield(app.LeftPanel, 'numeric');
            app.LEN_PSM_GT.Limits = [0 Inf];
            app.LEN_PSM_GT.Visible = 'off';
            app.LEN_PSM_GT.Value = 100.5;
            app.ThicknessnmEditFieldLabel = uilabel(app.LeftPanel);
            app.ThicknessnmEditFieldLabel.Visible = 'off';
            app.ThicknessnmEditFieldLabel.Text = 'Thickness (nm)';
            app.T_PSM_GT = uieditfield(app.LeftPanel, 'numeric');
            app.T_PSM_GT.Limits = [0 Inf];
            app.T_PSM_GT.Visible = 'off';
            app.T_PSM_GT.Value = 7.5;
            app.DispersityEditFieldLabel = uilabel(app.LeftPanel);
            app.DispersityEditFieldLabel.Visible = 'off';
            app.DispersityEditFieldLabel.Text = 'Dispersity';
            app.Dispersity = uieditfield(app.LeftPanel, 'numeric');
            app.Dispersity.Limits = [0 Inf];
            app.Dispersity.Visible = 'off';
            app.Dispersity.Value = 0.1;
            app.DiameternmEditFieldLabel = uilabel(app.LeftPanel);
            app.DiameternmEditFieldLabel.Visible = 'off';
            app.DiameternmEditFieldLabel.Text = 'Diameter (nm)';
            app.D_SP_GT = uieditfield(app.LeftPanel, 'numeric');
            app.D_SP_GT.Limits = [0 Inf];
            app.D_SP_GT.Visible = 'off';
            app.D_SP_GT.Value = 50;
            app.ParticleEditFieldLabel = uilabel(app.LeftPanel);
            app.ParticleEditFieldLabel.Text = 'Particle #';
            app.Particle_GT_FX = uieditfield(app.LeftPanel, 'numeric');
            app.Particle_GT_FX.Limits = [0 100];
            app.Particle_GT_FX.Value = 20;
            app.BlurryparticleEditFieldLabel = uilabel(app.LeftPanel);
            app.BlurryparticleEditFieldLabel.Text = 'Blurry particle #';
            app.Particle_NON_FX = uieditfield(app.LeftPanel, 'numeric');
            app.Particle_NON_FX.Limits = [0 100];
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Text = '+';
            app.Particle_GT_RD = uieditfield(app.LeftPanel, 'numeric');
            app.Particle_GT_RD.Limits = [0 100];
            app.fixedLabel = uilabel(app.LeftPanel);
            app.fixedLabel.Text = 'fixed';
            app.randLabel = uilabel(app.LeftPanel);
            app.randLabel.Text = '* rand()';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '+';
            app.randomIMFP = uieditfield(app.LeftPanel, 'numeric');
            app.randomIMFP.Limits = [0 500];
            app.randLabel_2 = uilabel(app.LeftPanel);
            app.randLabel_2.Text = '* rand()';
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '+';
            app.randomD = uieditfield(app.LeftPanel, 'numeric');
            app.randomD.Limits = [0 Inf];
            app.FilterradiusLabel = uilabel(app.LeftPanel);
            app.FilterradiusLabel.Text = 'Filter radius';
            app.F_R_FX = uieditfield(app.LeftPanel, 'numeric');
            app.F_R_FX.Limits = [0 Inf];
            app.MotionblurDLabel = uilabel(app.LeftPanel);
            app.MotionblurDLabel.Text = 'Motion blur D';
            app.Diffusivity = uieditfield(app.LeftPanel, 'numeric');
            app.Diffusivity.Limits = [0 Inf];
            app.Diffusivity.Value = 20;
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '+';
            app.F_R_RD = uieditfield(app.LeftPanel, 'numeric');
            app.F_R_RD.Limits = [0 Inf];
            app.Blur_Model_select = uibuttongroup(app.LeftPanel);
            app.Blur_Model_select.SelectionChangedFcn = createCallbackFcn(app, @Blur_Model_selectSelectionChanged, true);
            app.Blur_Model_select.Title = 'Blurry particle model:';
            app.samePTC = uiradiobutton(app.Blur_Model_select);
            app.samePTC.Text = 'Same particle';
            app.samePTC.Value = true;
            app.SP_PTC = uiradiobutton(app.Blur_Model_select);
            app.SP_PTC.Text = 'Sphere';
            app.pxLabel = uilabel(app.LeftPanel);
            app.pxLabel.Text = 'px';
            app.nm2s1Label = uilabel(app.LeftPanel);
            app.nm2s1Label.Text = 'nm2s-1';
            app.BL_SP_SZ = uieditfield(app.LeftPanel, 'numeric');
            app.BL_SP_SZ.Limits = [0 Inf];
            app.BL_SP_SZ.ValueChangedFcn = createCallbackFcn(app, @BL_SP_SZValueChanged, true);
            app.BL_SP_SZ.Visible = 'off';
            app.BL_SP_SZ.Value = 1;
            app.BL_SP_unit = uilabel(app.LeftPanel);
            app.BL_SP_unit.Visible = 'off';
            app.BL_SP_unit.Text = 'nm';
            app.BL_SP_D = uilabel(app.LeftPanel);
            app.BL_SP_D.Visible = 'off';
            app.BL_SP_D.Text = 'D';
            app.ParticleoverlayButtonGroup = uibuttongroup(app.LeftPanel);
            app.ParticleoverlayButtonGroup.Title = 'Particle overlay';
            app.yesButton = uiradiobutton(app.ParticleoverlayButtonGroup);
            app.yesButton.Text = 'yes';
            app.yesButton.Value = true;
            app.noButton = uiradiobutton(app.ParticleoverlayButtonGroup);
            app.noButton.Text = 'no';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.PreviewButton = uibutton(app.RightPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.Image2 = uiimage(app.RightPanel);
            app.BackgroundLabel = uilabel(app.RightPanel);
            app.BackgroundLabel.Text = 'Background:';
            app.ParticleLabel = uilabel(app.RightPanel);
            app.ParticleLabel.Text = 'Particle:';
            app.Label_11 = uilabel(app.RightPanel);
            app.Label_11.Text = '';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '';
            app.MeanLabel = uilabel(app.RightPanel);
            app.MeanLabel.Text = 'Mean:';
            app.MeanLabel_2 = uilabel(app.RightPanel);
            app.MeanLabel_2.Text = 'Mean:';
            app.StdLabel = uilabel(app.RightPanel);
            app.StdLabel.Text = 'Std:';
            app.StdLabel_2 = uilabel(app.RightPanel);
            app.StdLabel_2.Text = 'Std:';
            app.Label_12 = uilabel(app.RightPanel);
            app.Label_12.Text = '';
            app.Label_4 = uilabel(app.RightPanel);
            app.Label_4.Text = '';
            app.GenerateButton = uibutton(app.RightPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.SamplenumberEditFieldLabel = uilabel(app.RightPanel);
            app.SamplenumberEditFieldLabel.Text = 'Sample number';
            app.SamplenumberEditField = uieditfield(app.RightPanel, 'numeric');
            app.SamplenumberEditField.Limits = [1 10000];
            app.SamplenumberEditField.Value = 100;
            app.GroundtruthpathLabel = uilabel(app.RightPanel);
            app.GroundtruthpathLabel.Text = 'Ground truth path';
            app.GroundtruthpathEditField = uieditfield(app.RightPanel, 'text');
            app.GroundtruthpathEditField.Editable = 'off';
            app.GroundtruthpathEditField.Value = 'label';
            app.ImagepathEditFieldLabel = uilabel(app.RightPanel);
            app.ImagepathEditFieldLabel.Text = 'Image path';
            app.ImagepathEditField = uieditfield(app.RightPanel, 'text');
            app.ImagepathEditField.Editable = 'off';
            app.ImagepathEditField.Value = 'train';
            app.doneN = uilabel(app.RightPanel);
            app.doneN.Text = '';
            app.sampleN = uilabel(app.RightPanel);
            app.sampleN.Text = '';
            app.SNRLabel = uilabel(app.RightPanel);
            app.SNRLabel.Text = 'SNR:';
            app.SNRValue = uilabel(app.RightPanel);
            app.SNRValue.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end